classdef Image_filter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Image_Filter                matlab.ui.Figure
        ImageAxes                   matlab.ui.control.UIAxes
        ImageLabel                  matlab.ui.control.Label
        LoadImageButton             matlab.ui.control.Button
        FilterAnimationSwitchLabel  matlab.ui.control.Label
        FilterAnimationSwitch       matlab.ui.control.Switch
        CalculateButton             matlab.ui.control.Button
        KernelEditFieldLabel        matlab.ui.control.Label
        KernelEditField             matlab.ui.control.EditField
        NcolumnsEditFieldLabel      matlab.ui.control.Label
        Ncolumns                    matlab.ui.control.NumericEditField
        WEditFieldLabel             matlab.ui.control.Label
        Weight                      matlab.ui.control.NumericEditField
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        UpdateKernelButton          matlab.ui.control.Button
        MrowsEditFieldLabel         matlab.ui.control.Label
        Mrows                       matlab.ui.control.NumericEditField
        FiltertypeDropDownLabel     matlab.ui.control.Label
        Filtertype                  matlab.ui.control.DropDown
        RatioEditFieldLabel         matlab.ui.control.Label
        Ratio                       matlab.ui.control.NumericEditField
        ScaleButton                 matlab.ui.control.Button
        RotateButton                matlab.ui.control.Button
        AngleLabel                  matlab.ui.control.Label
        Angle                       matlab.ui.control.NumericEditField
        GrayScaleButton             matlab.ui.control.Button
        MINFilterButton             matlab.ui.control.Button
        MAXFilterButton             matlab.ui.control.Button
        WindowsizeEditFieldLabel    matlab.ui.control.Label
        WindowsizeEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        im =0; % image
        imname =0; % image name
        imflag = 0; % image load
        kernel = 0; % kernel
        flag = 0; % kernel loaded
        window = 0;
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
        try
            app.im = imread(imagefile);
        catch ME
            % If problem reading image, display error message
            uialert(app.Image_Filter, ME.message, 'Image Error');
            return;
        end   
        imshow(app.im,'Parent',app.ImageAxes)
        app.imname = imagefile;
        app.StatusLamp.Color = 'green';
        app.imflag = 1;
        end


        function updateKernel(app)
            switch app.Filtertype.Value
                
                case 'Custom'
                    app.kernel = strrep(app.KernelEditField.Value,'[','');
                    app.kernel = strrep(app.kernel,']','');
                    app.kernel = strrep(app.kernel,';',' ');
                    app.kernel = str2double(split(app.kernel));
                    try
                        app.kernel = reshape(app.kernel,[round(app.Mrows.Value) round(app.Ncolumns.Value)]);
                    catch ME
                        uialert(app.Image_Filter,ME.message,'Size mismatch');
                        return;
                    end
                    if app.Weight.Value == -1
                        W = sum( app.kernel(:) );
                        if W == 0
                            W = 1;
                        end
                        app.kernel = app.kernel/W;
                    else
                    app.kernel = app.kernel/round(app.Weight.Value);
                    end
                    app.kernel = transpose(app.kernel);
                    
                case 'Gaussian Blur (3x3)'
                    sigma = 15;
                    N = 3;
                    app.kernel = zeros(N,N);
                    W = 0;
                    for i = 1:N
                         for j =1:N
                            sq = (i-3)^2 + (j-3)^2;
                            app.kernel(i,j) = (1/(2*pi*sigma^2))*exp(-1*sq/(2*sigma^2));
                            W = W + app.kernel(i,j);
                         end
                    end
                    app.kernel = app.kernel/W;
                    app.Mrows.Value = 3;
                    app.Ncolumns.Value = 3;
                    app.Weight.Value = round(W,2);
                                       
                case 'Gaussian Blur (5x5)'
                    sigma = 15;
                    N = 5;
                    app.kernel = zeros(N,N);
                    W = 0;
                    for i = 1:N
                         for j =1:N
                            sq = (i-3)^2 + (j-3)^2;
                            app.kernel(i,j) = (1/(2*pi*sigma^2))*exp(-1*sq/(2*sigma^2));
                            W = W + app.kernel(i,j);
                         end
                    end
                    app.kernel = app.kernel/W;
                    app.Mrows.Value = 5;
                    app.Ncolumns.Value = 5;
                    app.Weight.Value = round(W,2);
                        
                case 'Bokeh (lens) Blur Circle'
                    app.kernel = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;0 0 0 0 0 0 0 1 1 1 1 1 1 1 0 0 0 0 0 0;0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0;0 0 0 0 1 1 1 1 0 0 0 0 1 1 1 1 1 0 0 0;0 0 0 1 1 1 0 0 0 0 0 0 0 0 1 1 1 0 0 0;0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 1 1 1 0 0;0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0 0;0 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 1 1 1 0;0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0;0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0;0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0;0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0;0 1 1 1 0 0 0 0 0 0 0 0 0 0 0 0 0 1 1 0;0 0 1 1 0 0 0 0 0 0 0 0 0 0 0 0 1 1 1 0;0 0 1 1 1 0 0 0 0 0 0 0 0 0 0 1 1 1 0 0;0 0 0 1 1 1 0 0 0 0 0 0 0 0 1 1 1 0 0 0;0 0 0 0 1 1 1 1 0 0 0 0 0 1 1 1 1 0 0 0;0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0;0 0 0 0 0 0 0 1 1 1 1 1 1 1 0 0 0 0 0 0;0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
                    app.kernel = app.kernel/sum( app.kernel(:) );
                    app.Mrows.Value = size(app.kernel,1);
                    app.Ncolumns.Value = size(app.kernel,2);
                    app.Weight.Value = sum( app.kernel(:) );
                    
                case 'Bokeh (lens) Blur Hex'
                    app.kernel = [0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0;0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0;0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0;0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0;0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0;0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0;0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0;0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0;1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0;0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0;0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0;0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0;0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0;0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0;0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0;0 0 0 0 1 1 1 1 1 1 1 1 1 1 1 1 0 0 0 0;0 0 0 0 0 1 1 1 1 1 1 1 1 1 1 0 0 0 0 0;0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0;0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
                    app.kernel = app.kernel/sum( app.kernel(:) );
                    app.Mrows.Value = size(app.kernel,1);
                    app.Ncolumns.Value = size(app.kernel,2);
                    app.Weight.Value = sum( app.kernel(:) );
                    
                case 'Mean Blur'
                    app.kernel = [1 1 1; 1 1 1; 1 1 1];
                    app.kernel = app.kernel/sum( app.kernel(:) );
                    app.Mrows.Value = size(app.kernel,1);
                    app.Ncolumns.Value = size(app.kernel,2);
                    app.Weight.Value = sum( app.kernel(:) );
                    
                case 'Edge Filter X'
                    app.kernel = [-1 0 1; -2 0 2; -1 0 1];
                    app.Mrows.Value = size(app.kernel,1);
                    app.Ncolumns.Value = size(app.kernel,2);
                    app.Weight.Value = 1;
                  
                case 'Edge Filter Y'
                    app.kernel = [-1 -2 -1; 0 0 0; 1 2 1];
                    app.Mrows.Value = size(app.kernel,1);
                    app.Ncolumns.Value = size(app.kernel,2);
                    app.Weight.Value = 1;
            end
            
            app.KernelEditField.Value = mat2str(round(app.kernel,2));
            app.flag = 1;
        end


        function [Y] = bil(~,I, ratio)
            [h, w] = size(I);
            H = round(ratio * h);
            W = round(ratio * w);
            Y = zeros(H,W);
            hs = (h/H);
            ws = (w/W);
            for i=1:H
                y = (hs * i) + (0.5 * (1 - 1/ratio));
                for j=1:W
                    x = (ws * j) + (0.5 * (1 - 1/ratio));
                     %// Any values out of acceptable range
                     x(x < 1) = 1;
                     x(x > h - 0.001) = h - 0.001;
                     x1 = floor(x);
                     x2 = x1 + 1;
                     y(y < 1) = 1;
                     y(y > w - 0.001) = w - 0.001;
                     y1 = floor(y);
                     y2 = y1 + 1;
                     %// 4 Neighboring Pixels
                     NP1 = I(y1,x1);
                     NP2 = I(y1,x2);
                     NP3 = I(y2,x1);
                     NP4 = I(y2,x2);
                     %// 4 Pixels Weights
                     PW1 = (y2-y)*(x2-x);
                     PW2 = (y2-y)*(x-x1);
                     PW3 = (x2-x)*(y-y1);
                     PW4 = (y-y1)*(x-x1);
                     Y(i,j) = PW1 * NP1 + PW2 * NP2 + PW3 * NP3 + PW4 * NP4;
                end
             end
        end
        
        
        function graymin(app,A)
            [r,c]=size(A);
            iml=zeros(r,c);
            n=app.window;
            z=ceil(r/10);
            k=(n+1)/2;
            app.StatusLamp.Color='red';
            for i=k:r-(k-1)
                for j=k:c-(k-1)
                    W = A(i-(k-1):i+(k-1),j-(k-1):j+(k-1));
                    Min=min(W(:));
                    iml(i,j)=Min;
                end
                if strcmp(app.FilterAnimationSwitch.Value,'On')
                        if mod(i,z)==0
                             imshow(uint8(iml),'Parent',app.ImageAxes);
                             drawnow;
                        end
                end
            end
            iml=uint8(iml);
            app.im=iml;
            imwrite(iml,strcat(app.imname(1:strlength(app.imname)-4),' min.jpg'));
            app.StatusLamp.Color = 'g';
            uialert(app.Image_Filter,'Min Filter applied!!','Success!!');
            imshow(iml,'Parent',app.ImageAxes)
        end
        
        
        function rgbmin(app,A)
            n=app.window; % size of the kernal window
            k=(n+1)/2;
            [r, c, d]=size(A);
            z=ceil(r/10); %the smaller the number u divide with the faster the rate of animation 
            iml=zeros(r,c,d);
            app.StatusLamp.Color = 'red';
            for p=1:d
                L=A(:,:,p);
                for i=(k:r-(k-1))
                    for j=(k:c-(k-1))
                        M=L(i-(k-1):i+(k-1),j-(k-1):j+(k-1));
                        MIN=min(M(:));
                        iml(i,j,p)=MIN;
                    end
                    if strcmp(app.FilterAnimationSwitch.Value,'On')
                        if mod(i,z)==0
                             imshow(uint8(iml),'Parent',app.ImageAxes);
                             drawnow;
                        end
                    end
                end
            end
            iml=uint8(iml);
            app.im=iml;
            imwrite(iml,strcat(app.imname(1:strlength(app.imname)-4),' min.jpg'));
            app.StatusLamp.Color = 'g';
            uialert(app.Image_Filter,'Min Filter applied!!','Success!!');
            imshow(iml,'Parent',app.ImageAxes)
        end
        
        function graymax(app,A)
            [r, c]=size(A);
            imh=zeros(r,c);
            n=app.window;
            z=ceil(r/10);
            k=(n+1)/2;
            app.StatusLamp.Color = 'red';
            for i=k:r-(k-1)
                for j=k:c-(k-1)
                    W=A(i-(k-1):i+(k-1),j-(k-1):j+(k-1));
                    Max=max(W(:));
                    imh(i,j)=Max;
                end
                if strcmp(app.FilterAnimationSwitch.Value,'On')
                        if mod(i,z)==0
                             imshow(uint8(imh),'Parent',app.ImageAxes);
                             drawnow;
                        end
                end
            end
            imh=uint8(imh);
            app.im=imh;
            imwrite(imh,strcat(app.imname(1:strlength(app.imname)-4),' max.jpg'));
            app.StatusLamp.Color = 'g';
            uialert(app.Image_Filter,'Max Filter applied!!','Success');
            imshow(imh,'Parent',app.ImageAxes)
        end
        
        function rgbmax(app,A)
            n=app.window; % size of the kernal window
            k=(n+1)/2;
            [r, c, d]=size(A);
            z=ceil(r/10);
            imh=zeros(r,c,d);
            app.StatusLamp.Color = 'red';
            for p=1:d
                L=A(:,:,p);
                for i=(k:r-(k-1))
                    for j=(k:c-(k-1))
                        M=L(i-(k-1):i+(k-1),j-(k-1):j+(k-1));
                        MAX=max(M(:));
                        imh(i,j,p)=MAX;
                    end
                    if strcmp(app.FilterAnimationSwitch.Value,'On')
                        if mod(i,z)==0
                             imshow(uint8(imh),'Parent',app.ImageAxes);
                             drawnow;
                        end
                    end
                end
            end
            imh=uint8(imh);
            app.im=imh;
            imwrite(imh,strcat(app.imname(1:strlength(app.imname)-4),' max.jpg'));
            app.StatusLamp.Color = 'g';
            uialert(app.Image_Filter,'Max Filter applied!!','Success!');
            imshow(imh,'Parent',app.ImageAxes)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                  fname = [p f];
                  updateimage(app, fname);
             end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.StatusLamp.Color = 'red';
            
            if app.flag == 0
                uialert(app.Image_Filter,'Note please update the kernel !!','Kernel not updated');
                return;
            end
            
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            
            imshow(app.im,'Parent',app.ImageAxes);
            pause(1);
            
            [m,n,d] = size(app.im);
            out = zeros(m,n);
            N = round(app.Ncolumns.Value);
            M = round(app.Mrows.Value);
            Im = padarray(app.im,[ floor(M/2) , floor(N/2) ]);
            for i = 1:m
                 for j =1:n
                    for k = 1:d
                    temp = Im(i:i+M-1, j:j+N-1, k);
                    temp = double(temp);
                    conv = temp .* app.kernel;
                    out(i,j,k) = sum( conv(:) );
                    end
                 end
                 
                 if strcmp(app.FilterAnimationSwitch.Value,'On')
                    if mod(i,10) == 0
                        %cla(app.ImageAxes,'reset');
                        imshow(uint8(out),'Parent',app.ImageAxes);
                        drawnow;
                    end
                 end
                 
             end

             out = uint8(out);
             imwrite(out,strcat(app.imname(1:strlength(app.imname)-4),' out.jpg'));
             imshow(out,'Parent',app.ImageAxes)
             app.StatusLamp.Color = 'green';
        end

        % Value changed function: Weight
        function WeightValueChanged(app, event)
            if app.Weight.Value == 0
                uialert(app.Image_Filter,' Value of w cannot be zero!!','Value Error')
                app.Weight.Value = -1;
                return;
            end
            app.flag = 0;
        end

        % Button pushed function: UpdateKernelButton
        function UpdateKernelButtonPushed(app, event)
            updateKernel(app);
        end

        % Value changed function: Mrows
        function MrowsValueChanged(app, event)
            app.flag = 0;
        end

        % Value changed function: Ncolumns
        function NcolumnsValueChanged(app, event)
            app.flag = 0;
        end

        % Value changed function: KernelEditField
        function KernelEditFieldValueChanged(app, event)
            app.flag = 0;
        end

        % Value changed function: Filtertype
        function FiltertypeValueChanged(app, event)
            app.flag = 0;
        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            img1 = app.im;
            [m,n,d] = size(img1);
            if m~=n
                uialert(app.Image_Filter,'Unable to scale non-square images','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            
            app.StatusLamp.Color = 'red';           
            ratio = app.Ratio.Value;
            if d == 1
                imgans = bil(app,double(img1),ratio);
            end
            if d == 3
                r = bil(app,double(img1(:,:,1)),ratio);
                g = bil(app,double(img1(:,:,2)),ratio);
                b = bil(app,double(img1(:,:,3)),ratio);
                imgans = cat(3,r,g,b);
            end
            imgans = uint8(imgans);
            app.im = imgans;
            imwrite(imgans,strcat(app.imname(1:strlength(app.imname)-4),' out.jpg'));
            imshow(imgans,'Parent',app.ImageAxes)
            uialert(app.Image_Filter,'Scaling done !!','Success');
            app.StatusLamp.Color = 'green';
        end

        % Value changed function: Ratio
        function RatioValueChanged(app, event)
            if app.Ratio.Value <= 0
                uialert(app.Image_Filter,'Ratio cannot be zero or lesser than zero !!','Value Error')
                app.Ratio.Value = 1;
                return;
            end
            if app.Ratio.Value >3
                uialert(app.Image_Filter,'Ratio cannot be greater than 3 !!','Value Error')
                app.Ratio.Value = 1;
                return;
            end
        end

        % Value changed function: Angle
        function AngleValueChanged(app, event)
            value = app.Angle.Value;
            if value > 360 || value < -360
                uialert(app.Image_Filter,'Angle should be between -360 to 360 !!','Angle Error');
                app.Angle.Value = 0;
                return;
            end
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            
            img1 = app.im;
            [m,n,d] = size(img1);
           
            rowsi = m;
            colsi = n;   
            
            app.StatusLamp.Color = 'red'; 
            angle = app.Angle.Value;
            
            rads = 2*pi*angle/360;
            R = [cos(rads) -sin(rads); sin(rads) cos(rads)];
            rowsf = ceil( rowsi*abs(cos(rads)) + colsi*abs(sin(rads)) );
            colsf = ceil( rowsi*abs(sin(rads)) + colsi*abs(cos(rads)) );
            
            % define an array withcalculated dimensionsand fill the array  with zeros ie.,black
            imgans = uint8(zeros([rowsf colsf d]));
            
            %calculating center of original
            xo=ceil(rowsi/2);
            yo=ceil(colsi/2);
            %final image
            midx=ceil(rowsf/2);
            midy=ceil(colsf/2);
            
            % in this loop we calculate corresponding coordinates of pixel of A
            % for each pixel of C, and its intensity will be  assigned after checking
            % weather it lie in the bound of A (original image)
            % Here we will be using inverse as we will be getting the co-ordinates of
            % the new image from the old one
            for i=1:rowsf
                for j=1:colsf
                    cor = R \ [(i-midx) ; (j-midy)];
                    x=round(cor(1))+xo;
                    y=round(cor(2))+yo;
                    if (x>=1 && y>=1 && x<=size(img1,1) &&  y<=size(img1,2) )
                        if d == 1
                            imgans(i,j)=img1(x,y);
                        end
                        if d == 3
                            imgans(i,j,:)=img1(x,y,:);
                        end                       
                    end
                end
            end
            
            app.im = imgans;
            imwrite(imgans,strcat(app.imname(1:strlength(app.imname)-4),' out.jpg'));
            imshow(imgans,'Parent',app.ImageAxes)
            uialert(app.Image_Filter,'Rotation done !!','Success');
            app.StatusLamp.Color = 'green';
        end

        % Button pushed function: GrayScaleButton
        function GrayScaleButtonPushed(app, event)
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            app.StatusLamp.Color = 'red';
            app.im = rgb2gray(app.im);
            imwrite(app.im,strcat(app.imname(1:strlength(app.imname)-4),' gray.jpg'));
            imshow(app.im,'Parent',app.ImageAxes)
            uialert(app.Image_Filter,'Gray conversion done !!','Success');
            app.StatusLamp.Color = 'green';
        end

        % Button pushed function: MINFilterButton
        function MINFilterButtonPushed(app, event)
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            if (app.window<3 || mod(app.window,2)==0)
                uialert(app.Image_Filter,'Window size must be > 2 and odd','Incompattible window size')
                return;
            end
            A=app.im;
            A=double(A);
            N=ndims(A);
            if(N==2)
                %input image is in grayscale
                graymin(app,A);
            else
                rgbmin(app,A);
            end
        end

        % Value changed function: WindowsizeEditField
        function WindowsizeEditFieldValueChanged(app, event)
            value = app.WindowsizeEditField.Value;
            app.window=value;
            if (app.window<3 || mod(app.window,2)==0)
                uialert(app.Image_Filter,'Window size must be > 2 and odd','Incompattible window size')
                return;
            end
        end

        % Button pushed function: MAXFilterButton
        function MAXFilterButtonPushed(app, event)
            if app.imflag == 0
                uialert(app.Image_Filter,'Please load an input image','Image Error');
                app.StatusLamp.Color = [1 1 1];
                return;
            end
            if (app.window<3 || mod(app.window,2)==0)
                uialert(app.Image_Filter,'Window size must be > 2 and odd','Incompattible window size')
                return;
            end
            A=app.im;
            A=double(A);
            N=ndims(A);
            if(N==2)
                %input image is in grayscale
                graymax(app,A);
            else
                rgbmax(app,A);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Image_Filter = uifigure('Visible', 'off');
            app.Image_Filter.Name = 'Image Filter';
            app.ImageAxes = uiaxes(app.Image_Filter);
            title(app.ImageAxes, '')
            xlabel(app.ImageAxes, '')
            ylabel(app.ImageAxes, '')
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageLabel = uilabel(app.Image_Filter);
            app.ImageLabel.Text = 'Image';
            app.LoadImageButton = uibutton(app.Image_Filter, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.FilterAnimationSwitchLabel = uilabel(app.Image_Filter);
            app.FilterAnimationSwitchLabel.Text = 'Filter Animation';
            app.FilterAnimationSwitch = uiswitch(app.Image_Filter, 'slider');
            app.FilterAnimationSwitch.Value = 'On';
            app.CalculateButton = uibutton(app.Image_Filter, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.KernelEditFieldLabel = uilabel(app.Image_Filter);
            app.KernelEditFieldLabel.Text = 'Kernel';
            app.KernelEditField = uieditfield(app.Image_Filter, 'text');
            app.KernelEditField.ValueChangedFcn = createCallbackFcn(app, @KernelEditFieldValueChanged, true);
            app.KernelEditField.Tooltip = {'Add all your filter values in value following format'; '[1 2 3; 4 5 6; 7 8 9]'; ''; 'Note: Please don''t use special characters, letters or words !!'};
            app.KernelEditField.Value = '[1 2 3; 4 5 6; 7 8 9]';
            app.NcolumnsEditFieldLabel = uilabel(app.Image_Filter);
            app.NcolumnsEditFieldLabel.Text = 'N (columns)';
            app.Ncolumns = uieditfield(app.Image_Filter, 'numeric');
            app.Ncolumns.ValueChangedFcn = createCallbackFcn(app, @NcolumnsValueChanged, true);
            app.Ncolumns.Value = 3;
            app.WEditFieldLabel = uilabel(app.Image_Filter);
            app.WEditFieldLabel.Text = 'W';
            app.Weight = uieditfield(app.Image_Filter, 'numeric');
            app.Weight.ValueChangedFcn = createCallbackFcn(app, @WeightValueChanged, true);
            app.Weight.Tooltip = {'Specify the weight for the kernel'; ''; 'Note;'; '-1 --> use the sum of kernel as weight'};
            app.Weight.Value = -1;
            app.StatusLampLabel = uilabel(app.Image_Filter);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.Image_Filter);
            app.UpdateKernelButton = uibutton(app.Image_Filter, 'push');
            app.UpdateKernelButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateKernelButtonPushed, true);
            app.UpdateKernelButton.Text = 'Update Kernel';
            app.MrowsEditFieldLabel = uilabel(app.Image_Filter);
            app.MrowsEditFieldLabel.Text = 'M (rows)';
            app.Mrows = uieditfield(app.Image_Filter, 'numeric');
            app.Mrows.ValueChangedFcn = createCallbackFcn(app, @MrowsValueChanged, true);
            app.Mrows.Value = 3;
            app.FiltertypeDropDownLabel = uilabel(app.Image_Filter);
            app.FiltertypeDropDownLabel.Text = 'Filter type';
            app.Filtertype = uidropdown(app.Image_Filter);
            app.Filtertype.Items = {'Custom', 'Gaussian Blur (3x3)', 'Gaussian Blur (5x5)', 'Bokeh (lens) Blur Hex', 'Bokeh (lens) Blur Circle', 'Mean Blur', 'Edge Filter X', 'Edge Filter Y', '', '', '', ''};
            app.Filtertype.ValueChangedFcn = createCallbackFcn(app, @FiltertypeValueChanged, true);
            app.Filtertype.Value = 'Custom';
            app.RatioEditFieldLabel = uilabel(app.Image_Filter);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.Ratio = uieditfield(app.Image_Filter, 'numeric');
            app.Ratio.ValueChangedFcn = createCallbackFcn(app, @RatioValueChanged, true);
            app.Ratio.Tooltip = {'Use this to scale your image.'};
            app.Ratio.Value = 1;
            app.ScaleButton = uibutton(app.Image_Filter, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.RotateButton = uibutton(app.Image_Filter, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.AngleLabel = uilabel(app.Image_Filter);
            app.AngleLabel.Text = 'Angle';
            app.Angle = uieditfield(app.Image_Filter, 'numeric');
            app.Angle.ValueChangedFcn = createCallbackFcn(app, @AngleValueChanged, true);
            app.Angle.Tooltip = {'Use this to scale your image.'};
            app.GrayScaleButton = uibutton(app.Image_Filter, 'push');
            app.GrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButtonPushed, true);
            app.GrayScaleButton.Text = 'GrayScale';
            app.MINFilterButton = uibutton(app.Image_Filter, 'push');
            app.MINFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MINFilterButtonPushed, true);
            app.MINFilterButton.Text = 'MIN Filter';
            app.MAXFilterButton = uibutton(app.Image_Filter, 'push');
            app.MAXFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MAXFilterButtonPushed, true);
            app.MAXFilterButton.Text = 'MAX Filter';
            app.WindowsizeEditFieldLabel = uilabel(app.Image_Filter);
            app.WindowsizeEditFieldLabel.Text = 'Window size';
            app.WindowsizeEditField = uieditfield(app.Image_Filter, 'numeric');
            app.WindowsizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowsizeEditFieldValueChanged, true);
            app.WindowsizeEditField.Value = 1;
            % Show the figure after all components are created
            app.Image_Filter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_filter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Image_Filter)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Image_Filter)
        end
    end
end